classdef GUI_Ver_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ConfigurationTab              matlab.ui.container.Tab
        Panel                         matlab.ui.container.Panel
        AddIPgroupButton              matlab.ui.control.Button
        ADDButton                     matlab.ui.control.Button
        IPAddressEditFieldLabel       matlab.ui.control.Label
        IPAddressEditField            matlab.ui.control.EditField
        AddSpecificIPLabel            matlab.ui.control.Label
        AddagroupofIPsIPConvention1011XLabel  matlab.ui.control.Label
        FromSpinnerLabel              matlab.ui.control.Label
        FromSpinner                   matlab.ui.control.Spinner
        DELETEButton                  matlab.ui.control.Button
        IPAddressListTextAreaLabel    matlab.ui.control.Label
        IPAddressListTextArea         matlab.ui.control.TextArea
        BSSTextAreaLabel              matlab.ui.control.Label
        BSSTextArea                   matlab.ui.control.TextArea
        RefreshVerifyButton           matlab.ui.control.Button
        PasswordEditFieldLabel        matlab.ui.control.Label
        PasswordEditField             matlab.ui.control.EditField
        SSIDEditFieldLabel            matlab.ui.control.Label
        SSIDEditField                 matlab.ui.control.EditField
        ScanSSIDButton                matlab.ui.control.Button
        ControlNetworkConfiguationLabel  matlab.ui.control.Label
        TestNetworkConfiguationLabel  matlab.ui.control.Label
        ConnectPiButton               matlab.ui.control.Button
        ToSpinnerLabel                matlab.ui.control.Label
        ToSpinner                     matlab.ui.control.Spinner
        ListofAvailableSSIDsTextAreaLabel  matlab.ui.control.Label
        ListofAvailableSSIDsTextArea  matlab.ui.control.TextArea
        AddSSIDButton                 matlab.ui.control.Button
        FinishedLampLabel             matlab.ui.control.Label
        FinishedLamp                  matlab.ui.control.Lamp
        FinishedLamp_2Label           matlab.ui.control.Label
        FinishedLamp_2                matlab.ui.control.Lamp
        ControlIPLabel                matlab.ui.control.Label
        VerificationLabel             matlab.ui.control.Label
        DeleteUnverifiedPisButton     matlab.ui.control.Button
        IPtoscanDropDownLabel         matlab.ui.control.Label
        IPtoscanDropDown              matlab.ui.control.DropDown
        TestParametersTab             matlab.ui.container.Tab
        RunTestButton                 matlab.ui.control.Button
        IperfTypeDropDownLabel        matlab.ui.control.Label
        IperfTypeDropDown             matlab.ui.control.DropDown
        NumberofTrialsSpinnerLabel    matlab.ui.control.Label
        NumberofTrialsSpinner         matlab.ui.control.Spinner
        TestFolderEditFieldLabel      matlab.ui.control.Label
        TestFolderEditField           matlab.ui.control.EditField
        ScenarioNameEditFieldLabel    matlab.ui.control.Label
        ScenarioNameEditField         matlab.ui.control.EditField
        DurationSpinner_2Label        matlab.ui.control.Label
        DurationSpinner               matlab.ui.control.Spinner
        IperfServerEditFieldLabel     matlab.ui.control.Label
        IperfServerEditField          matlab.ui.control.EditField
        Tree                          matlab.ui.container.Tree
        ListofAcccessPointsNode       matlab.ui.container.TreeNode
        ScenarioListTextAreaLabel     matlab.ui.control.Label
        ScenarioListTextArea          matlab.ui.control.TextArea
        ServerIperfTypeNumTrialsFolderFileLabel  matlab.ui.control.Label
        AnalysisTab                   matlab.ui.container.Tab
        ContextMenu                   matlab.ui.container.ContextMenu
        ListofdevicesMenu             matlab.ui.container.Menu
    end

    
    properties (Access = public)
        %% Temporary storage of parameters
        IPList = {}
        IP_Num = 1
        BSS = {}
        BSS_Num = 1
        test = {}
        scenario_num = 1
    end
    
    methods (Access = public)
        
        function add_ip(app, t)
            cm = app.ListofdevicesMenu;
            if app.IP_Num == 1
                app.IPList(app.IP_Num).ip = t;
                app.IPList(app.IP_Num).verified = 'no';
                app.IPList(app.IP_Num).m = uimenu(cm,'Text',app.IPList(app.IP_Num).ip);
                app.IP_Num = app.IP_Num + 1;
                update_text(app)
            elseif ~check_duplicate(app,app.IPList, t)
                app.IPList(app.IP_Num).ip = t;
                app.IPList(app.IP_Num).verified = 'no';
                app.IPList(app.IP_Num).m = uimenu(cm,'Text',app.IPList(app.IP_Num).ip);
                app.IP_Num = app.IP_Num + 1;
                update_text(app)
            end
        end
        
        function isFlag = check_duplicate(app, inStruct, fieldname)
            % inStruct is the name of the structure or an array of structures to search
            % fieldName is the name of the field for which the function
            % returns 1 if duplicate is found
            isFlag = 0;
            for i=1:app.IP_Num-1
                if strcmp(inStruct(i).ip, fieldname)
                    isFlag = 1;
                    return
                end
            end
        end
        
        function isFlag_2 = check_duplicate_SSID(app, inStruct, fieldname)
            % inStruct is the name of the structure or an array of structures to search
            % fieldName is the name of the field for which the function
            % returns 1 if duplicate is found
            isFlag_2 = 0;
            for i=1:app.IP_Num-1
                if strcmp(inStruct(i).SSID, fieldname)
                    isFlag_2 = 1;
                    return
                end
            end
        end
        
        function verify(app)
            verified = 0;
            if app.IP_Num == 1
                uialert(app.UIFigure,'There is no addded IPs. Pleas input a valid IP','Invalid Input');
                return
            end
            for i=1:app.IP_Num-1
                newStr = split(app.IPList(i).ip,".");
                if i == 1
                    first = newStr(4);
                end
                last = newStr(4);
            end
            input = strcat(first, " ", last);
            cmdStr = strcat("ssh pi@10.1.1.4 ","bash get_ip.sh -r ", input);
            %%cmdStr = strcat("bash get_ip.sh -r ", input);
            [status,cmdout] = unix(cmdStr);
            if status ~= 0
                uialert(app.UIFigure,'Build failed','Invalid Input');
                return
            end
            
            %% This updates verification status
            for i = 1: app.IP_Num - 1
                if contains(cmdout,strcat(string(app.IPList(i).ip), " is up" ))
                    app.IPList(i).verified = 'yes';
                    update_text(app)
                    verified = verified + 1;
                end
            end
        end
        %% Delete unverified ips
        function delete_unverfied(app)
            count = 1;
            while count <= app.IP_Num - 1
                if strcmp(app.IPList(count).verified, 'no')
                    delete_IP(app,app.IPList(count).ip)
                else
                    count = count + 1;
                end
            end
        end
        
        function add_bss(app,t,u)
            if app.BSS_Num == 1
                app.BSS(app.BSS_Num).SSID = t;
                %%app.BSS(app.BSS_Num).C_IP = s;
                app.BSS(app.BSS_Num).T_IP = u;
                app.BSSTextArea.Value{end + 1} = [t '       ' u];
                app.BSS_Num = app.BSS_Num + 1;
            elseif ~check_duplicate_SSID(app,app.BSS, t)
                app.BSS(app.BSS_Num).SSID = t;
                %%app.BSS(app.BSS_Num).C_IP = s;
                app.BSS(app.BSS_Num).T_IP = u;
                app.BSSTextArea.Value{end + 1} = [t '       ' u];
                app.BSS_Num = app.BSS_Num + 1;
            end 
            
            
            count = 1;
            count_2 = 1;
            while count < app.IP_Num 
                cm = app.IPList(count).m;
                while count_2 < app.BSS_Num     
                    m = uimenu(cm,'Text',app.BSS(count_2).SSID);
                    count_2 = count_2 + 1;
                end
                count = count + 1;
                count_2 = 1;
            end
        end

        function delete_IP(app, t)
            for i=1:app.IP_Num - 2
                if strcmp(app.IPList(i).ip, t)
                    delete(app.IPList(i).m);
                    app.IPList(i) = [];
                    app.IP_Num = app.IP_Num -1;
                end
            end
            update_text(app);
        end
        
        function update_text(app)
            newStr = strings([1,app.IP_Num]);   %% For updating text fields
            newStr2 = strings([1,app.IP_Num]);  %% For updating drop down menu
            % Update the text fields
            for i=1:app.IP_Num - 1
                newStr{i} =[app.IPList(i).ip '    ' app.IPList(i).verified];
                newStr2{i} = app.IPList(i).ip;
            end
            app.IPAddressListTextArea.Value= newStr;
            app.IPtoscanDropDown.Items = newStr2;
        end
        function update_scn_text(app)
            newStr = strings([1,app.scenario_num]);
            % Update the text fields
            
            for i=1:app.scenario_num -1
                newStr{i} = sprintf('%s', app.test(i).text);
            end
            app.ScenarioListTextArea.Value= newStr;
        end
        function create_node(app, t)
            N = uitreenode(app.Tree);
            N.text = t;
        end
        function clear_node(app)
            a = app.Tree.Children;
            a.delete;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            PATH = getenv('PATH');
            setenv('PATH', [PATH 'C:\Users\JohnCarlo.Laude001\Documents\MATLAB\Examples\R2020a\matlab\AppDesignerTreeExample']);
        end

        % Callback function
        function NumberofIPsSpinnerValueChanged(app, event)
            app.NumberofIPsSpinner.Value= event.Value;
            
        end

        % Value changed function: IperfTypeDropDown
        function IperfTypeDropDownValueChanged(app, event)
            app.IperfTypeDropDown.Value = event;
            
        end

        % Value changed function: NumberofTrialsSpinner
        function NumberofTrialsSpinnerValueChanged(app, event)
            app.NumberofTrialsSpinner.Value = event.Value;
            
        end

        % Value changed function: TestFolderEditField
        function TestFolderEditFieldValueChanged(app, event)
            app.TestFolderEditField.Value = event.Value;
            
        end

        % Value changed function: ScenarioNameEditField
        function ScenarioNameEditFieldValueChanged(app, event)
            app.ScenarioNameEditField.Value = event.Value;
            
        end

        % Button pushed function: RunTestButton
        function RunTestButtonPushed(app, event)
            %%iperf_serv = app.IperfServerEditField.Value;
            dir = app.TestFolderEditField.Value;
            system(strcat("mkdir ", dir));
            % Creates a directory folder to store scenario
            %%num_IPs = string(app.NumberofIPsSpinner.Value);
            %%iperf_type = app.IperfTypeDropDown.Value;
            num_trials = string(app.NumberofTrialsSpinner.Value);
            dur = string(app.DurationSpinner.Value);
            %%filename = app.ScenarioNameEditField.Value;
            if app.IP_Num == 1
                uialert(app.UIFigure,'There are no available IPs to do a test','No IPs available');
                return
            end
            first  = strsplit(app.IPList(1).ip,'.');
            last = strsplit(app.IPList(app.IP_Num).ip, '.');
            cmdStr = strcat("echo ",num_trials, " ", dur, " ",first{end}," ", last{end}, "|", "offloading_test.sh");
            status = unix(cmdStr);
            if status ~= 0
                uialert(app.UIFigure,'Build failed','Invalid Input');
                return
            end
        end

        % Value changed function: IperfServerEditField
        function IperfServerEditFieldValueChanged(app, event)
            app.IperfServerEditField.Value = event.Value;
            
        end

        % Value changing function: IperfServerEditField
        function IperfServerEditFieldValueChanging(app, event)
            app.IperfServerEditField.Value = event.Value;
            
        end

        % Button pushed function: ADDButton
        function ADDButtonPushed(app, event)
            t = app.IPAddressEditField.Value;
            add_ip(app,t);
        end

        % Button pushed function: AddIPgroupButton
        function AddIPgroupButtonPushed(app, event)
            a = app.FromSpinner.Value;
            b = app.ToSpinner.Value;
            if b < a
                uialert(app.UIFigure,'Input is out of range','Invalid Input');
                return
            end
            c = '10.1.1.';
            for i = a: b
                t = strcat(c,num2str(i));
                add_ip(app,t)
            end
        end

        % Button pushed function: DELETEButton
        function DELETEButtonPushed(app, event)
            t = app.IPAddressEditField.Value;
            delete_IP(app, t);
        end

        % Button pushed function: RefreshVerifyButton
        function RefreshVerifyButtonPushed(app, event)
            %% Turn off lamp
            app.FinishedLampLabel.Text = 'In Progress';
            app.FinishedLamp.Color = [1 0 0];
            drawnow()
            % Verification Function
            verify(app);
            %% Turn on lamp
            app.FinishedLampLabel.Text = 'Finished';
            app.FinishedLamp.Color = [0 1 0];
            drawnow()
        end

        % Button pushed function: ScanSSIDButton
        function ScanSSIDButtonPushed(app, event)
            % Turn off lamp
            app.FinishedLamp_2Label.Text = 'In Progress';
            app.FinishedLamp_2.Color = [1 0 0];
            t = app.IPtoscanDropDown.Value;
            drawnow()
            if app.IP_Num == 1
                uialert(app.UIFigure,'There are no available IPs to do a test. Please input a valid IP','No IPs available');
                app.FinishedLamp_2Label.Text = 'Finished';
                app.FinishedLamp_2.Color = [0 1 0];
                return
            end
            
            cmdStr = strcat("ssh pi@",t," bash LIST_SSID.sh");
            [status,cmdout] = unix(cmdStr);
            if status ~= 0
                uialert(app.UIFigure,'Build failed, Check to see that chosen IP is client and not access point and that wlan0 interface is active','Invalid Input');
                app.FinishedLamp_2Label.Text = 'Finished';
                app.FinishedLamp_2.Color = [0 1 0];
                drawnow()
                return
            end
            % Turn on lamp
            app.ListofAvailableSSIDsTextArea.Value = cmdout;
            app.FinishedLamp_2Label.Text = 'Finished';
            app.FinishedLamp_2.Color = [0 1 0];
            drawnow()
        end

        % Button pushed function: AddSSIDButton
        function AddSSIDButtonPushed(app, event)
            t =  app.SSIDEditField.Value;
            %%s = app.IPtoscanEditField.Value;
            u = app.IPtoscanEditField.Value;
            add_bss(app,t,u)
        end

        % Callback function
        function AddScenarioButtonPushed(app, event)
            iperf_serv = app.IperfServerEditField.Value;
            dir = app.TestFolderEditField.Value;
            system(strcat("mkdir ", dir));
            % Creates a directory folder to store scenario
            num_IPs = "2";
            iperf_type = app.IperfTypeDropDown.Value;
            num_trials = string(app.NumberofTrialsSpinner.Value);
            dur = string(app.DurationSpinner.Value);
            filename = app.ScenarioNameEditField.Value;
            app.test(app.scenario_num).scenario = strcat("iperf_bash.sh"," ",iperf_serv," ",num_IPs," ",iperf_type," ",num_trials, " ", dur, " ", dir," ",filename);
            app.test(app.scenario_num).text = strcat(iperf_serv,"    ",iperf_type,"     ",num_trials, "    ", dur, "     ", dir,"    ",filename);
            app.scenario_num = app.scenario_num + 1;
            update_scn_text(app)
        end

        % Button pushed function: DeleteUnverifiedPisButton
        function DeleteUnverifiedPisButtonPushed(app, event)
            delete_unverfied(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.Panel = uipanel(app.ConfigurationTab);
            app.AddIPgroupButton = uibutton(app.Panel, 'push');
            app.AddIPgroupButton.ButtonPushedFcn = createCallbackFcn(app, @AddIPgroupButtonPushed, true);
            app.AddIPgroupButton.Text = 'Add IP group';
            app.ADDButton = uibutton(app.Panel, 'push');
            app.ADDButton.ButtonPushedFcn = createCallbackFcn(app, @ADDButtonPushed, true);
            app.ADDButton.Text = 'ADD';
            app.IPAddressEditFieldLabel = uilabel(app.Panel);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditField = uieditfield(app.Panel, 'text');
            app.IPAddressEditField.Value = '10.1.1.1';
            app.AddSpecificIPLabel = uilabel(app.Panel);
            app.AddSpecificIPLabel.Text = 'Add Specific IP';
            app.AddagroupofIPsIPConvention1011XLabel = uilabel(app.Panel);
            app.AddagroupofIPsIPConvention1011XLabel.Text = 'Add a group of IPs, IP Convention: 10.1.1.X';
            app.FromSpinnerLabel = uilabel(app.Panel);
            app.FromSpinnerLabel.Text = 'From';
            app.FromSpinner = uispinner(app.Panel);
            app.FromSpinner.Limits = [1 Inf];
            app.FromSpinner.Value = 1;
            app.DELETEButton = uibutton(app.Panel, 'push');
            app.DELETEButton.ButtonPushedFcn = createCallbackFcn(app, @DELETEButtonPushed, true);
            app.DELETEButton.Text = 'DELETE';
            app.IPAddressListTextAreaLabel = uilabel(app.ConfigurationTab);
            app.IPAddressListTextAreaLabel.Text = 'IP Address List';
            app.IPAddressListTextArea = uitextarea(app.ConfigurationTab);
            app.BSSTextAreaLabel = uilabel(app.ConfigurationTab);
            app.BSSTextAreaLabel.Text = 'BSS';
            app.BSSTextArea = uitextarea(app.ConfigurationTab);
            app.BSSTextArea.Value = {'SSID                  TEST IP'};
            app.RefreshVerifyButton = uibutton(app.ConfigurationTab, 'push');
            app.RefreshVerifyButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshVerifyButtonPushed, true);
            app.RefreshVerifyButton.Text = 'Refresh/Verify';
            app.PasswordEditFieldLabel = uilabel(app.ConfigurationTab);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.ConfigurationTab, 'text');
            app.PasswordEditField.Value = 'password';
            app.SSIDEditFieldLabel = uilabel(app.ConfigurationTab);
            app.SSIDEditFieldLabel.Text = 'SSID';
            app.SSIDEditField = uieditfield(app.ConfigurationTab, 'text');
            app.SSIDEditField.Value = 'SSID Name';
            app.ScanSSIDButton = uibutton(app.ConfigurationTab, 'push');
            app.ScanSSIDButton.ButtonPushedFcn = createCallbackFcn(app, @ScanSSIDButtonPushed, true);
            app.ScanSSIDButton.Text = 'Scan SSID';
            app.ControlNetworkConfiguationLabel = uilabel(app.ConfigurationTab);
            app.ControlNetworkConfiguationLabel.Text = 'Control Network Configuation ';
            app.TestNetworkConfiguationLabel = uilabel(app.ConfigurationTab);
            app.TestNetworkConfiguationLabel.Text = 'Test Network Configuation ';
            app.ConnectPiButton = uibutton(app.ConfigurationTab, 'push');
            app.ConnectPiButton.Text = 'Connect Pi';
            app.ToSpinnerLabel = uilabel(app.ConfigurationTab);
            app.ToSpinnerLabel.Text = 'To';
            app.ToSpinner = uispinner(app.ConfigurationTab);
            app.ToSpinner.Limits = [2 Inf];
            app.ToSpinner.Value = 2;
            app.ListofAvailableSSIDsTextAreaLabel = uilabel(app.ConfigurationTab);
            app.ListofAvailableSSIDsTextAreaLabel.Text = 'List of Available SSIDs';
            app.ListofAvailableSSIDsTextArea = uitextarea(app.ConfigurationTab);
            app.AddSSIDButton = uibutton(app.ConfigurationTab, 'push');
            app.AddSSIDButton.ButtonPushedFcn = createCallbackFcn(app, @AddSSIDButtonPushed, true);
            app.AddSSIDButton.Text = 'Add SSID';
            app.FinishedLampLabel = uilabel(app.ConfigurationTab);
            app.FinishedLampLabel.Text = 'Finished';
            app.FinishedLamp = uilamp(app.ConfigurationTab);
            app.FinishedLamp_2Label = uilabel(app.ConfigurationTab);
            app.FinishedLamp_2Label.Text = 'Finished';
            app.FinishedLamp_2 = uilamp(app.ConfigurationTab);
            app.ControlIPLabel = uilabel(app.ConfigurationTab);
            app.ControlIPLabel.Text = 'Control IP';
            app.VerificationLabel = uilabel(app.ConfigurationTab);
            app.VerificationLabel.Text = 'Verification';
            app.DeleteUnverifiedPisButton = uibutton(app.ConfigurationTab, 'push');
            app.DeleteUnverifiedPisButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteUnverifiedPisButtonPushed, true);
            app.DeleteUnverifiedPisButton.Text = 'Delete Unverified Pis';
            app.IPtoscanDropDownLabel = uilabel(app.ConfigurationTab);
            app.IPtoscanDropDownLabel.Text = 'IP to scan';
            app.IPtoscanDropDown = uidropdown(app.ConfigurationTab);
            app.IPtoscanDropDown.Items = {};
            app.IPtoscanDropDown.Value = {};
            app.TestParametersTab = uitab(app.TabGroup);
            app.TestParametersTab.Title = 'Test Parameters';
            app.RunTestButton = uibutton(app.TestParametersTab, 'push');
            app.RunTestButton.ButtonPushedFcn = createCallbackFcn(app, @RunTestButtonPushed, true);
            app.RunTestButton.Text = 'Run Test';
            app.IperfTypeDropDownLabel = uilabel(app.TestParametersTab);
            app.IperfTypeDropDownLabel.Text = 'Iperf Type';
            app.IperfTypeDropDown = uidropdown(app.TestParametersTab);
            app.IperfTypeDropDown.Items = {'-c', '-d', '-r'};
            app.IperfTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @IperfTypeDropDownValueChanged, true);
            app.IperfTypeDropDown.Value = '-c';
            app.NumberofTrialsSpinnerLabel = uilabel(app.TestParametersTab);
            app.NumberofTrialsSpinnerLabel.Text = 'Number of Trials';
            app.NumberofTrialsSpinner = uispinner(app.TestParametersTab);
            app.NumberofTrialsSpinner.Limits = [1 Inf];
            app.NumberofTrialsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofTrialsSpinnerValueChanged, true);
            app.NumberofTrialsSpinner.Value = 5;
            app.TestFolderEditFieldLabel = uilabel(app.TestParametersTab);
            app.TestFolderEditFieldLabel.Text = 'Test Folder';
            app.TestFolderEditField = uieditfield(app.TestParametersTab, 'text');
            app.TestFolderEditField.ValueChangedFcn = createCallbackFcn(app, @TestFolderEditFieldValueChanged, true);
            app.TestFolderEditField.Value = 'Test';
            app.ScenarioNameEditFieldLabel = uilabel(app.TestParametersTab);
            app.ScenarioNameEditFieldLabel.Text = 'Scenario Name';
            app.ScenarioNameEditField = uieditfield(app.TestParametersTab, 'text');
            app.ScenarioNameEditField.ValueChangedFcn = createCallbackFcn(app, @ScenarioNameEditFieldValueChanged, true);
            app.ScenarioNameEditField.Value = 'Scenario';
            app.DurationSpinner_2Label = uilabel(app.TestParametersTab);
            app.DurationSpinner_2Label.Text = 'Duration';
            app.DurationSpinner = uispinner(app.TestParametersTab);
            app.DurationSpinner.Limits = [1 Inf];
            app.DurationSpinner.Value = 10;
            app.IperfServerEditFieldLabel = uilabel(app.TestParametersTab);
            app.IperfServerEditFieldLabel.Text = 'Iperf  Server';
            app.IperfServerEditField = uieditfield(app.TestParametersTab, 'text');
            app.IperfServerEditField.ValueChangedFcn = createCallbackFcn(app, @IperfServerEditFieldValueChanged, true);
            app.IperfServerEditField.ValueChangingFcn = createCallbackFcn(app, @IperfServerEditFieldValueChanging, true);
            app.IperfServerEditField.Value = '10.1.101.2';
            app.Tree = uitree(app.TestParametersTab);
            app.ListofAcccessPointsNode = uitreenode(app.Tree);
            app.ListofAcccessPointsNode.Text = 'List of Acccess Points';
            app.ScenarioListTextAreaLabel = uilabel(app.TestParametersTab);
            app.ScenarioListTextAreaLabel.Text = 'Scenario List';
            app.ScenarioListTextArea = uitextarea(app.TestParametersTab);
            app.ServerIperfTypeNumTrialsFolderFileLabel = uilabel(app.TestParametersTab);
            app.ServerIperfTypeNumTrialsFolderFileLabel.Text = 'Server, Iperf Type, Num Trials, Folder, File ';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.ConnectPiButton.ContextMenu = app.ContextMenu;
            app.ListofdevicesMenu = uimenu(app.ContextMenu);
            app.ListofdevicesMenu.Text = 'List of devices';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Ver_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end