classdef GUI_Ver_1_myles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RunTestButton               matlab.ui.control.Button
        IperfTypeDropDownLabel      matlab.ui.control.Label
        IperfTypeDropDown           matlab.ui.control.DropDown
        NumberofTrialsSpinnerLabel  matlab.ui.control.Label
        NumberofTrialsSpinner       matlab.ui.control.Spinner
        TestFolderEditFieldLabel    matlab.ui.control.Label
        TestFolderEditField         matlab.ui.control.EditField
        ScenarioNameEditFieldLabel  matlab.ui.control.Label
        ScenarioNameEditField       matlab.ui.control.EditField
        DurationSpinner_2Label      matlab.ui.control.Label
        DurationSpinner             matlab.ui.control.Spinner
        IperfServer1EditFieldLabel  matlab.ui.control.Label
        IperfServer1EditField       matlab.ui.control.EditField
        FirstGroupofPisstarttoendEditFieldLabel  matlab.ui.control.Label
        FirstGroupofPisstarttoendEditField  matlab.ui.control.EditField
        FirstGroupofPisstarttoendEditField_2  matlab.ui.control.EditField
        SecondGroupofPisstarttoendEditFieldLabel  matlab.ui.control.Label
        SecondGroupofPisstarttoendEditField  matlab.ui.control.EditField
        FirstGroupofPisstarttoendEditField_3  matlab.ui.control.EditField
        ConnectPistoSpecifiedNetworkButton  matlab.ui.control.Button
        SSIDandPasswordforFirstGroupofPisEditFieldLabel  matlab.ui.control.Label
        SSIDandPasswordforFirstGroupofPisEditField  matlab.ui.control.EditField
        SSIDandPasswordforFirstGroupofPisEditField_2  matlab.ui.control.EditField
        SSIDandPasswordforFirstGroupofPisEditField_3Label  matlab.ui.control.Label
        SSIDandPasswordforFirstGroupofPisEditField_3  matlab.ui.control.EditField
        SSIDandPasswordforFirstGroupofPisEditField_4  matlab.ui.control.EditField
        IperfServer2EditFieldLabel  matlab.ui.control.Label
        IperfServer2EditField       matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function NumberofIPsSpinnerValueChanged(app, event)
            app.NumberofIPsSpinner.Value= event.Value;
            
        end

        % Value changed function: IperfTypeDropDown
        function IperfTypeDropDownValueChanged(app, event)
            app.IperfTypeDropDown.Value = event.Value;
            
        end

        % Value changed function: NumberofTrialsSpinner
        function NumberofTrialsSpinnerValueChanged(app, event)
            app.NumberofTrialsSpinner.Value = event.Value;
            
        end

        % Value changed function: TestFolderEditField
        function TestFolderEditFieldValueChanged(app, event)
            app.TestFolderEditField.Value = event.Value;
            
        end

        % Value changed function: ScenarioNameEditField
        function ScenarioNameEditFieldValueChanged(app, event)
            app.ScenarioNameEditField.Value = event.Value;
            
        end

        % Button pushed function: RunTestButton
        function RunTestButtonPushed(app, event)
            iperf_serv = app.FirstGroupofPisstarttoendEditField.Value;
            dir = app.TestFolderEditField.Value;
            system(strcat("mkdir ", dir));
            % Creates a directory folder to store scenario
            num_IPs = string(app.NumberofIPsSpinner.Value);
            iperf_type = app.IperfTypeDropDown.Value;
            num_trials = string(app.NumberofTrialsSpinner.Value);
            dur = string(app.DurationSpinner.Value);
            filename = app.ScenarioNameEditField.Value;
            cmdStr = strcat("iperf_bash.sh"," ",iperf_serv," ",num_IPs," ",iperf_type," ",num_trials, " ", dur, " ", dir," ",filename);
            system(cmdStr);
            
        end

        % Callback function
        function FirstGroupofPisstarttoendEditFieldValueChanged(app, event)
            app.FirstGroupofPisstarttoendEditField.Value = event.Value;
            
        end

        % Callback function
        function FirstGroupofPisstarttoendEditFieldValueChanging(app, event)
            app.FirstGroupofPisstarttoendEditField.Value = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunTestButton = uibutton(app.UIFigure, 'push');
            app.RunTestButton.ButtonPushedFcn = createCallbackFcn(app, @RunTestButtonPushed, true);
            app.RunTestButton.Text = 'Run Test';
            app.IperfTypeDropDownLabel = uilabel(app.UIFigure);
            app.IperfTypeDropDownLabel.Text = 'Iperf Type';
            app.IperfTypeDropDown = uidropdown(app.UIFigure);
            app.IperfTypeDropDown.Items = {'-c', '-d', '-r'};
            app.IperfTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @IperfTypeDropDownValueChanged, true);
            app.IperfTypeDropDown.Value = '-c';
            app.NumberofTrialsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofTrialsSpinnerLabel.Text = 'Number of Trials';
            app.NumberofTrialsSpinner = uispinner(app.UIFigure);
            app.NumberofTrialsSpinner.Limits = [1 Inf];
            app.NumberofTrialsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofTrialsSpinnerValueChanged, true);
            app.NumberofTrialsSpinner.Value = 5;
            app.TestFolderEditFieldLabel = uilabel(app.UIFigure);
            app.TestFolderEditFieldLabel.Text = 'Test Folder';
            app.TestFolderEditField = uieditfield(app.UIFigure, 'text');
            app.TestFolderEditField.ValueChangedFcn = createCallbackFcn(app, @TestFolderEditFieldValueChanged, true);
            app.TestFolderEditField.Value = 'Test';
            app.ScenarioNameEditFieldLabel = uilabel(app.UIFigure);
            app.ScenarioNameEditFieldLabel.Text = 'Scenario Name';
            app.ScenarioNameEditField = uieditfield(app.UIFigure, 'text');
            app.ScenarioNameEditField.ValueChangedFcn = createCallbackFcn(app, @ScenarioNameEditFieldValueChanged, true);
            app.ScenarioNameEditField.Value = 'Scenario';
            app.DurationSpinner_2Label = uilabel(app.UIFigure);
            app.DurationSpinner_2Label.Text = 'Duration';
            app.DurationSpinner = uispinner(app.UIFigure);
            app.DurationSpinner.Limits = [1 Inf];
            app.DurationSpinner.Value = 10;
            app.IperfServer1EditFieldLabel = uilabel(app.UIFigure);
            app.IperfServer1EditFieldLabel.Text = 'Iperf  Server 1';
            app.IperfServer1EditField = uieditfield(app.UIFigure, 'text');
            app.IperfServer1EditField.Value = '192.168.1.200';
            app.FirstGroupofPisstarttoendEditFieldLabel = uilabel(app.UIFigure);
            app.FirstGroupofPisstarttoendEditFieldLabel.Text = 'First Group of Pi''s (start to end)';
            app.FirstGroupofPisstarttoendEditField = uieditfield(app.UIFigure, 'text');
            app.FirstGroupofPisstarttoendEditField.Value = '104';
            app.FirstGroupofPisstarttoendEditField_2 = uieditfield(app.UIFigure, 'text');
            app.FirstGroupofPisstarttoendEditField_2.Value = '101';
            app.SecondGroupofPisstarttoendEditFieldLabel = uilabel(app.UIFigure);
            app.SecondGroupofPisstarttoendEditFieldLabel.Text = 'Second Group of Pi''s (start to end)';
            app.SecondGroupofPisstarttoendEditField = uieditfield(app.UIFigure, 'text');
            app.SecondGroupofPisstarttoendEditField.Value = '110';
            app.FirstGroupofPisstarttoendEditField_3 = uieditfield(app.UIFigure, 'text');
            app.FirstGroupofPisstarttoendEditField_3.Value = '107';
            app.ConnectPistoSpecifiedNetworkButton = uibutton(app.UIFigure, 'push');
            app.ConnectPistoSpecifiedNetworkButton.Text = 'Connect Pi''s to Specified Network';
            app.SSIDandPasswordforFirstGroupofPisEditFieldLabel = uilabel(app.UIFigure);
            app.SSIDandPasswordforFirstGroupofPisEditFieldLabel.Text = 'SSID and Password for First Group of Pi''s';
            app.SSIDandPasswordforFirstGroupofPisEditField = uieditfield(app.UIFigure, 'text');
            app.SSIDandPasswordforFirstGroupofPisEditField.Value = 'password';
            app.SSIDandPasswordforFirstGroupofPisEditField_2 = uieditfield(app.UIFigure, 'text');
            app.SSIDandPasswordforFirstGroupofPisEditField_2.Value = 'UCaNLab_Research';
            app.SSIDandPasswordforFirstGroupofPisEditField_3Label = uilabel(app.UIFigure);
            app.SSIDandPasswordforFirstGroupofPisEditField_3Label.Text = 'SSID and Password for First Group of Pi''s';
            app.SSIDandPasswordforFirstGroupofPisEditField_3 = uieditfield(app.UIFigure, 'text');
            app.SSIDandPasswordforFirstGroupofPisEditField_3.Value = 'password';
            app.SSIDandPasswordforFirstGroupofPisEditField_4 = uieditfield(app.UIFigure, 'text');
            app.SSIDandPasswordforFirstGroupofPisEditField_4.Value = 'UCaNLab_Research2';
            app.IperfServer2EditFieldLabel = uilabel(app.UIFigure);
            app.IperfServer2EditFieldLabel.Text = 'Iperf  Server 2';
            app.IperfServer2EditField = uieditfield(app.UIFigure, 'text');
            app.IperfServer2EditField.Value = '192.168.2.200';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Ver_1_myles

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end